classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PortDropDown               matlab.ui.control.DropDown
        PortDropDownLabel          matlab.ui.control.Label
        ConnectDisconnectButton    matlab.ui.control.Button
        SingularityWarningLabel    matlab.ui.control.Label
        TorqueSwitch               matlab.ui.control.Switch
        TorqueSwitchLabel          matlab.ui.control.Label
        StatusPanel                matlab.ui.container.Panel
        StatusLabel                matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        ControlPanel               matlab.ui.container.Panel
        KGainSlider                matlab.ui.control.Slider
        KGainSliderLabel           matlab.ui.control.Label
        ShowButton                 matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        GoButton                   matlab.ui.control.Button
        ZeroButton                 matlab.ui.control.Button
        InitButton                 matlab.ui.control.Button
        ProgramArgumentsPanel      matlab.ui.container.Panel
        ProgramArgumentsLabel      matlab.ui.control.Label
        ProgramArgumentsEditField  matlab.ui.control.EditField
        ProgramSelectPanel         matlab.ui.container.Panel
        ProgramSelectLabel         matlab.ui.control.Label
        ProgramSelectDropdown      matlab.ui.control.DropDown
    end


    properties (Access = private)
        launcher;
    end

    methods (Access = private)


        function updateGUI(app)

            % If the robot is connected
            if ~isempty(app.launcher.realRobot)
                app.StatusLabel.Text = 'Connected';
                app.ConnectDisconnectButton.Text = 'Disconnect';
                app.StatusLamp.Color = [0,1,0]; % green
                app.TorqueSwitch.Visible = "on";
                app.SingularityWarningLabel.Visible = "on";

                % If the torque is on
                if app.launcher.realRobot.robotTorqueEnabled
                    app.TorqueSwitch.Value = "On";
                    app.ControlPanel.Visible = "on";

                    % If the torque is off
                else
                    app.TorqueSwitch.Value = "Off";
                    app.ControlPanel.Visible = "off";
                end

                % If the robot is not connected
            else
                app.ConnectDisconnectButton.Text = 'Connect';
                app.StatusLabel.Text = 'Disconnected';
                app.StatusLamp.Color = [1,0,0]; % green
                app.TorqueSwitch.Visible = "off";
                app.SingularityWarningLabel.Visible = "off";
                app.ControlPanel.Visible = "off";
            end


            % Check available COM ports and update PortDropDown
            availablePorts = serialportlist;
            if isempty(availablePorts)
                app.PortDropDown.Items = {'-'};
            else
                app.PortDropDown.Items = availablePorts;
            end

        end
    end

    methods
        function updateCallback(app)
            % This callback gets called by the launcher/program
            % Update Singularity Warning
            if app.launcher.virtualRobot.checkSingularity()
                app.SingularityWarningLabel.Text = "Singularity Warning";
                app.SingularityWarningLabel.FontColor = [1.00,0.47,0.15]; %orange
            else
                app.SingularityWarningLabel.Text = "No Singularity";
                app.SingularityWarningLabel.FontColor = [0,1,0]; %green
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            close all


            screenSize = get(0, 'ScreenSize'); % Get the screen size
            appWidth = screenSize(3) / 3; % App width is 1/3 of the screen width
            appHeight = screenSize(4); % App height is the full screen height

            % Set the position of the app
            app.UIFigure.Position = [screenSize(3) - appWidth, 1, appWidth, appHeight];

            % Set UIFigure to visible
            app.UIFigure.Visible = 'on';

            % Add Path to Launcher
            currentDir = fileparts(mfilename('fullpath'));
            launcherPath = fullfile(currentDir, 'src', 'Programs');
            addpath(launcherPath);

            % Initialize the Launcher
            app.launcher = Launcher.getInstance(app);
            app.ProgramSelectDropdown.Items = app.launcher.getPrograms;

            app.updateGUI;

            app.ProgramSelectDropdownValueChanged;

        end

        % Button pushed function: ConnectDisconnectButton
        function ConnectDisconnectButtonPushed(app, event)


            if strcmp(app.ConnectDisconnectButton.Text, 'Connect')
                % Connect Button
                selectedPort = app.PortDropDown.Value;
                if strcmp(selectedPort, '-')
                    app.launcher.connect(); % Call without argument if '-' is selected
                else
                    app.launcher.connect(selectedPort); % Call with the selected port
                end
            else
                % Disconnect Button
                app.launcher.disconnect;
            end

            app.updateGUI;


        end

        % Value changed function: TorqueSwitch
        function TorqueSwitchValueChanged(app, event)
            if strcmp(app.TorqueSwitch.Value, 'On')
                app.launcher.realRobot.setRobotTorque(1);
            else
                app.launcher.realRobot.setRobotTorque(0);
            end

            app.updateGUI;

        end

        % Value changed function: ProgramSelectDropdown
        function ProgramSelectDropdownValueChanged(app, event)
            % Value changed function: ProgramDropDown
            selectedProgram = app.ProgramSelectDropdown.Value;

            % Clear the Program Arguments Field
            app.ProgramArgumentsEditField.Value = '';

            % Handle different cases based on the selected program
            switch selectedProgram
                case 'SetJoints'
                    app.ProgramArgumentsLabel.Text = 'Joint Angles [deg]';
                    app.ProgramArgumentsEditField.Placeholder = 'y, x, z, x';
                    app.ProgramArgumentsEditField.Visible = "on";

                case 'SetPosition'
                    app.ProgramArgumentsLabel.Text = 'TCP Position [mm]';
                    app.ProgramArgumentsEditField.Placeholder = 'x, y, z';
                    app.ProgramArgumentsEditField.Visible = "on";

                case 'Trajectory2D'
                    app.ProgramArgumentsLabel.Text = 'Duration [s]';
                    app.ProgramArgumentsEditField.Placeholder = 't';
                    app.ProgramArgumentsEditField.Visible = "on";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app.launcher)
            delete(app.UIFigure);
            close all

        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            Kp = app.KGainSlider.Value;
            app.launcher.launchProgram('SetJoints',[22.5;22.5;22.5;22.5;], 'Kp', Kp);
            app.updateGUI;
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            Kp = app.KGainSlider.Value;
            app.launcher.launchProgram('SetJoints',[0;0;0;0], 'Kp', Kp);
            app.updateGUI;

        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)

            Kp = app.KGainSlider.Value;
            
            programName = app.ProgramSelectDropdown.Value;
            args = app.ProgramArgumentsEditField.Value;

            [is_valid, error_msg] = app.launcher.checkProgramArgs(programName, args);

            % Check if the arguments are not valid
            if ~is_valid
                % Display an alert with the error message
                uialert(app.UIFigure, error_msg, 'Invalid Arguments', 'Icon', 'error');
            else
                % Convert the arguments string to a numeric column vector
                args_numeric = str2num(args); % Convert string to numeric array
                if isempty(args_numeric)
                    app.launcher.launchProgram(programName);
                else
                    args_numeric = args_numeric(:); % Ensure it's a column vector
                end
                if ~strcmp(programName, 'SetJoints')
                    Kp = Kp/3;
                end

                % Launch the program with numeric arguments
                app.launcher.launchProgram(programName, args_numeric, 'Kp', Kp);
            end
            app.updateGUI;

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            delete(app.launcher.activeProgram)
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)

            numJoints = 4;
            joint_limits = rad2deg(app.launcher.virtualRobot.JOINT_ANGLE_LIMITS);
            joint_limits(1,:) = joint_limits(1,:)*0.7;
            joint_limits(2,:) = joint_limits(2,:)*0.7;
            joint_limits(3,:) = joint_limits(3,:)*0.35;
            joint_limits(4,:) = joint_limits(4,:)*0.7;

            % Set Elbow to 90 deg
            app.launcher.launchProgram('SetJoints', [0;0;0;90], 'precision_deg',3, 'Kp', 3);

            % Turn Around z-Axis
            app.launcher.launchProgram('SetJoints', [0;0;180;90], 'precision_deg',5, 'Kp', 2);
            app.launcher.launchProgram('SetJoints', [0;0;-180;90], 'precision_deg',5, 'Kp', 2);

            % Touch behind
            app.launcher.launchProgram('SetJoints', [-45;0;-90;120], 'precision_deg',5, 'Kp', 1.5);

            % Touch forward
            app.launcher.launchProgram('SetJoints', [45;0;90;120], 'precision_deg',5, 'Kp', 1.5);

            % Touch right
            app.launcher.launchProgram('SetJoints', [0;-45;180;120], 'precision_deg',5, 'Kp', 1.5);

            % Touch left
            app.launcher.launchProgram('SetJoints', [0;45;0;120], 'precision_deg',5, 'Kp', 1.5);

            % Go zero
            app.launcher.launchProgram('SetJoints', zeros(numJoints, 1), 'precision_deg',3, 'Kp', 3);

            % Move to forward limit
            app.launcher.launchProgram('SetJoints', joint_limits(:,1), 'precision_deg',5, 'Kp', 2);

            % Move to backward limit
            app.launcher.launchProgram('SetJoints', joint_limits(:,2), 'precision_deg',5, 'Kp', 2);

            % Move back to zero
            app.launcher.launchProgram('SetJoints', zeros(numJoints, 1), 'precision_deg',3, 'Kp', 3);
            
            app.updateGUI;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Visible = 'off';
            app.ProgramSelectPanel = uipanel(app.ControlPanel);
            app.ProgramSelectDropdown = uidropdown(app.ProgramSelectPanel);
            app.ProgramSelectDropdown.ValueChangedFcn = createCallbackFcn(app, @ProgramSelectDropdownValueChanged, true);
            app.ProgramSelectLabel = uilabel(app.ProgramSelectPanel);
            app.ProgramSelectLabel.Text = 'Program';
            app.ProgramArgumentsPanel = uipanel(app.ControlPanel);
            app.ProgramArgumentsPanel.Title = 'Panel';
            app.ProgramArgumentsEditField = uieditfield(app.ProgramArgumentsPanel, 'text');
            app.ProgramArgumentsEditField.Placeholder = 'q1, q2, q3, q4';
            app.ProgramArgumentsLabel = uilabel(app.ProgramArgumentsPanel);
            app.ProgramArgumentsLabel.Text = 'Value';
            app.InitButton = uibutton(app.ControlPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.ZeroButton = uibutton(app.ControlPanel, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.GoButton = uibutton(app.ControlPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ShowButton = uibutton(app.ControlPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.KGainSliderLabel = uilabel(app.ControlPanel);
            app.KGainSliderLabel.Text = 'K - Gain';
            app.KGainSlider = uislider(app.ControlPanel);
            app.KGainSlider.Limits = [0 5];
            app.KGainSlider.Value = 1;
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Panel2';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Disconnected';
            app.TorqueSwitchLabel = uilabel(app.UIFigure);
            app.TorqueSwitchLabel.Visible = 'off';
            app.TorqueSwitchLabel.Text = 'Torque';
            app.TorqueSwitch = uiswitch(app.UIFigure, 'slider');
            app.TorqueSwitch.ValueChangedFcn = createCallbackFcn(app, @TorqueSwitchValueChanged, true);
            app.TorqueSwitch.Visible = 'off';
            app.TorqueSwitch.Value = 'On';
            app.SingularityWarningLabel = uilabel(app.UIFigure);
            app.SingularityWarningLabel.Visible = 'off';
            app.SingularityWarningLabel.Text = 'Singularity Warning';
            app.ConnectDisconnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDisconnectButtonPushed, true);
            app.ConnectDisconnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.PortDropDown.Value = 'COM3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end